classdef EEGPreprocessingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        EEGPreprocessingToolLabel       matlab.ui.control.Label
        Step4Check                      matlab.ui.control.Label
        Step3Check                      matlab.ui.control.Label
        Step2Check                      matlab.ui.control.Label
        Step1Check                      matlab.ui.control.Label
        Step0Check                      matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        Step4RereferenceDataLabel       matlab.ui.control.Label
        Step3DropBadChannelsLabel       matlab.ui.control.Label
        Step2ApplyFiltersLabel          matlab.ui.control.Label
        Step1ResampleDataLabel          matlab.ui.control.Label
        Step0LoadDataLabel              matlab.ui.control.Label
        NotchFilterFrequencyEditField   matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        ResamplingFrequencyEditField_3  matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        ResamplingFrequencyEditField_2  matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        ResamplingFrequencyEditField    matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        RereferenceDataButton           matlab.ui.control.Button
        DropBadChannelsButton           matlab.ui.control.Button
        ApplyFiltersButton              matlab.ui.control.Button
        ResampleButton                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        SelectEEGdataLabel              matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        EEGPreprocessingToolLabel_2     matlab.ui.control.Label
        Step5Check                      matlab.ui.control.Label
        NextButton_2                    matlab.ui.control.Button
        Step6RemoveBadEpochsLabel       matlab.ui.control.Label
        Step5EpochDataLabel             matlab.ui.control.Label
        RemoveBadEpochsButton           matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        PreStimulusWindowsEditField     matlab.ui.control.NumericEditField
        EditFieldLabel_6                matlab.ui.control.Label
        PostStimulusWindowsEditField    matlab.ui.control.NumericEditField
        EditFieldLabel_5                matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        EpochDataButton                 matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NotriggersButton                matlab.ui.control.RadioButton
        MydataincludetriggersButton     matlab.ui.control.RadioButton
        Tab_3                           matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        EEGPreprocessingToolLabel_3     matlab.ui.control.Label
        Step7Check                      matlab.ui.control.Label
        NextButton_3                    matlab.ui.control.Button
        PleaseselectanelectrodeLabel    matlab.ui.control.Label
        Step7ApplyICALabel              matlab.ui.control.Label
        ElectrodeDropDown               matlab.ui.control.DropDown
        ElectrodeDropDownLabel          matlab.ui.control.Label
        RejectComponentsButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        RunningICApleasewaitLabel       matlab.ui.control.Label
        RunICAButton                    matlab.ui.control.Button
        Tab_4                           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        NobadchannelsdetectedLabel      matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        ExportData                      matlab.ui.control.Label
        EEGPreprocessingToolLabel_4     matlab.ui.control.Label
        Step9Check                      matlab.ui.control.Label
        Step8Check                      matlab.ui.control.Label
        Compare                         matlab.ui.control.Label
        Step9Applyartifactrejection     matlab.ui.control.Label
        Step8Interpolatebadchannels     matlab.ui.control.Label
        ComparefirstandlastdataButton   matlab.ui.control.Button
        ApplyartifactrejectionButton    matlab.ui.control.Button
        InterpolateBadChannelsButton    matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        current_step = 0;
        data;
        path = '';
        resample_frequency = 500;
        hpass_f = 0.5;
        lpass_f = 70;
        notch_f = 50;
        dropped_labels = {};
        mode = 'resting';
        data_events;
        event_types;
        event_values;
        event_read_flag = false;
        epoch_length = 1;
        overlap = 0.5;
        pre_stim = 0.2;
        post_stim = 0.8;
        electrodes_path;
        electrodes;
        comp;
        comp_num;
        elec = 'Not Selected';
    end
    
    methods (Access = public)
        
        function UpdateUI(app)
            if app.current_step == 0
                app.Step0LoadDataLabel.Enable = 'on';
                app.SelectEEGdataLabel.Enable = 'on';
                make_active(app, app.SelectButton);
            else
                app.Step0LoadDataLabel.Enable = 'off';
                app.SelectEEGdataLabel.Enable = 'off';
                make_passive(app, app.SelectButton);
            end

            if app.current_step == 1
                app.Step1ResampleDataLabel.Enable = 'on';
                app.ResamplingFrequencyEditField.Enable = 'on';
                app.EditFieldLabel.Enable = 'on';
                make_active(app, app.ResampleButton);
                app.Step0Check.Text = char(10003);
            else
                app.Step1ResampleDataLabel.Enable = 'off';
                app.ResamplingFrequencyEditField.Enable = 'off';
                app.EditFieldLabel.Enable = 'off';
                make_passive(app, app.ResampleButton);
            end

            if app.current_step == 2
                app.Step2ApplyFiltersLabel.Enable = 'on';
                app.ResamplingFrequencyEditField_2.Enable = 'on';
                app.EditFieldLabel_2.Enable = 'on';
                app.EditFieldLabel_3.Enable = 'on';
                app.EditFieldLabel_4.Enable = 'on';
                app.ResamplingFrequencyEditField_3.Enable = 'on';
                app.NotchFilterFrequencyEditField.Enable = 'on';
                make_active(app, app.ApplyFiltersButton);
                app.Step1Check.Text = char(10003);
            else
                app.Step2ApplyFiltersLabel.Enable = 'off';
                app.ResamplingFrequencyEditField_2.Enable = 'off';                
                app.EditFieldLabel_2.Enable = 'off';
                app.EditFieldLabel_3.Enable = 'off';
                app.EditFieldLabel_4.Enable = 'off';
                app.ResamplingFrequencyEditField_3.Enable = 'off';
                app.NotchFilterFrequencyEditField.Enable = 'off';
                make_passive(app, app.ApplyFiltersButton);
            end

            if app.current_step == 3
                app.Step3DropBadChannelsLabel.Enable = 'on';                
                make_active(app, app.DropBadChannelsButton);
                app.Step2Check.Text = char(10003);
            else
                app.Step3DropBadChannelsLabel.Enable = 'off';                
                make_passive(app, app.DropBadChannelsButton);
            end

            if app.current_step == 4
                app.Step4RereferenceDataLabel.Enable = 'on';                
                make_active(app, app.RereferenceDataButton);
                app.Step3Check.Text = char(10003);
            else
                app.Step4RereferenceDataLabel.Enable = 'off';                
                make_passive(app, app.RereferenceDataButton);
            end

            if app.current_step == 5
                app.Step5EpochDataLabel.Enable = 'on';                
                app.NotriggersButton.Enable = 'on';
                app.MydataincludetriggersButton.Enable = 'on';
                app.LengthEditField.Enable = 'on';
                app.LengthEditFieldLabel.Enable = 'on';
                app.OverlapEditFieldLabel.Enable = 'on';
                app.OverlapEditField.Enable = 'on';
                make_active(app, app.EpochDataButton);
                make_active(app, app.NextButton);
                app.Step4Check.Text = char(10003);
            else
                app.Step5EpochDataLabel.Enable = 'off';                
                app.NotriggersButton.Enable = 'off';
                app.MydataincludetriggersButton.Enable = 'off';
                app.LengthEditField.Enable = 'off';
                app.LengthEditFieldLabel.Enable = 'off';
                app.OverlapEditFieldLabel.Enable = 'off';
                app.OverlapEditField.Enable = 'off';
                make_passive(app, app.EpochDataButton);
                make_passive(app, app.NextButton);
            end

            if app.current_step == 6
                app.Step6RemoveBadEpochsLabel.Enable = 'on';                
                make_active(app, app.RemoveBadEpochsButton);
                app.Step5Check.Text = char(10003);
            else
                app.Step6RemoveBadEpochsLabel.Enable = 'off';                
                make_passive(app, app.RemoveBadEpochsButton);
            end

            if app.current_step == 7
                app.Step7ApplyICALabel.Enable = 'on';
                app.PleaseselectanelectrodeLabel.Enable = 'on';                
                app.Label.Enable = 'on';
                app.ElectrodeDropDown.Enable = 'on';
                app.ElectrodeDropDownLabel.Enable = 'on';
                make_active(app, app.RunICAButton);
                app.RunningICApleasewaitLabel.Enable = 'on';
                app.RejectComponentsButton.Enable = 'on';
                make_active(app, app.NextButton_2);
            else
                app.Step7ApplyICALabel.Enable = 'off';
                app.PleaseselectanelectrodeLabel.Enable = 'off';
                app.Label.Enable = 'off';
                app.ElectrodeDropDown.Enable = 'off';
                app.ElectrodeDropDownLabel.Enable = 'off';
                app.RunICAButton.Enable = 'off';
                app.RunningICApleasewaitLabel.Enable = 'off';
                make_passive(app, app.RejectComponentsButton);
                make_passive(app, app.NextButton_2);
                make_passive(app, app.RejectComponentsButton);
            end

            if app.current_step == 8
                app.Step8Interpolatebadchannels.Enable = 'on';
                make_active(app, app.InterpolateBadChannelsButton);
                make_active(app, app.NextButton_3);
                app.Step7Check.Text = char(10003);
            else
                app.Step8Interpolatebadchannels.Enable = 'off';
                make_passive(app, app.InterpolateBadChannelsButton);
                make_passive(app, app.NextButton_3);
            end

            if app.current_step == 9
                app.Step9Applyartifactrejection.Enable = 'on';
                make_active(app, app.ApplyartifactrejectionButton);
                app.Step8Check.Text = char(10003);
            else
                app.Step9Applyartifactrejection.Enable = 'off';
                make_passive(app, app.ApplyartifactrejectionButton);
            end

            if app.current_step == 10
                app.Compare.Enable = 'on';
                app.ExportData.Enable = 'on';
                make_active(app, app.ComparefirstandlastdataButton);
                make_active(app, app.ExportButton);
                app.Step9Check.Text = char(10003);
            else
                app.Compare.Enable = 'off';
                app.ExportData.Enable = 'off';
                make_passive(app, app.ComparefirstandlastdataButton);
                make_passive(app, app.ExportButton);                
            end
        end
    end
    
    methods (Access = private)
        
        function make_active(app, button)
            button.Enable = 'on';
            button.BackgroundColor = [0 0.5 0];
            button.FontColor = [1 1 1];
        end

        function make_passive(app, button)
            button.Enable = 'off';
            button.BackgroundColor = [0.85 0.85 0.85];
            button.FontColor = [0 0 0];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateUI(app);
            app.UITable.ColumnFormat = {'logical', 'char'};
            app.UITable.ColumnEditable = [true, false];
            eventTableData = {false, 'A' ; true, 'B'; true, 'C'};
            app.UITable.Data = eventTableData;
            
            app.UITable_2.ColumnFormat = {'logical', 'char'};
            app.UITable_2.ColumnEditable = [true, false];
            valueTableData = {false, 'A' ; true, 'B'; true, 'C'};
            app.UITable_2.Data = valueTableData;
            fieldtrip_path = fileparts(which('ft_defaults.m'));
            app.electrodes_path = fullfile(fieldtrip_path, 'template', 'electrode');
            app.electrodes = dir(app.electrodes_path);
            app.ElectrodeDropDown.Items = [{'Not Selected'} {app.electrodes(3:end).name}];            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
           
            % select the file
            [file, location] = uigetfile('*.*', 'Select your EEG data file');

            % check if the user has clicked cancel
            if isequal(file, 0)
                return;
            end

            % compose the path and load the data
            app.path = fullfile(location, file);
            app.data = load_data_0(app.path);            

            app.current_step = 1;
            UpdateUI(app);
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            app.data = resample_data_1(app.resample_frequency, app.data);
            app.current_step = 2;
            UpdateUI(app);
        end

        % Value changed function: ResamplingFrequencyEditField
        function ResamplingFrequencyEditFieldValueChanged(app, event)
            value = app.ResamplingFrequencyEditField.Value;
            app.resample_frequency = value;
        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            app.data = apply_filters_2(app.hpass_f, app.lpass_f, app.notch_f, app.data);
            app.current_step = 3;
            UpdateUI(app);
        end

        % Value changed function: ResamplingFrequencyEditField_2
        function ResamplingFrequencyEditField_2ValueChanged(app, event)
            value = app.ResamplingFrequencyEditField_2.Value;
            app.hpass_f = value;
        end

        % Value changed function: ResamplingFrequencyEditField_3
        function ResamplingFrequencyEditField_3ValueChanged(app, event)
            value = app.ResamplingFrequencyEditField_3.Value;
            app.lpass_f = value;
        end

        % Value changed function: NotchFilterFrequencyEditField
        function NotchFilterFrequencyEditFieldValueChanged(app, event)
            value = app.NotchFilterFrequencyEditField.Value;
            app.notch_f = value;
        end

        % Button pushed function: DropBadChannelsButton
        function DropBadChannelsButtonPushed(app, event)
            [app.data, app.dropped_labels] = drop_bad_channels_3(app.data);
            app.current_step = 4;
            UpdateUI(app);
        end

        % Button pushed function: RereferenceDataButton
        function RereferenceDataButtonPushed(app, event)
            app.data = rereference_data_4(app.data);
            app.current_step = 5;
            UpdateUI(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'No triggers')
                app.mode = 'resting';
            else
                app.mode = 'trigger';
                if (~app.event_read_flag)
                    app.data_events = ft_read_event(app.path);   
                    event_types_char = cellfun(@num2str, {app.data_events.type}, 'UniformOutput', false);
                    event_values_char = cellfun(@num2str, {app.data_events.value}, 'UniformOutput', false);
                    app.event_types = unique(event_types_char);
                    app.event_values = unique(event_values_char);                 
                    
                    num_types = length(app.event_types);
                    type_table_data = cell(num_types, 2);
                    type_table_data(:, 1) = {false};
                    type_table_data(:, 2) = app.event_types';
                    app.UITable.Data = type_table_data;

                    num_values = length(app.event_values);
                    value_table_data = cell(num_values, 2);
                    value_table_data(:, 1) = {false};
                    value_table_data(:, 2) = app.event_values';
                    app.UITable_2.Data = value_table_data;
                    
                    app.event_read_flag = true;
                end
            end

            if strcmp(app.mode, 'resting')
                app.Panel.Visible = "off";
                app.Panel.Enable = "off";
                app.Panel_2.Visible = "on";
                app.Panel_2.Enable = "on";
            else
                app.Panel.Visible = "on";
                app.Panel.Enable = "on";
                app.Panel_2.Visible = "off";
                app.Panel_2.Enable = "off";
            end


        end

        % Button pushed function: EpochDataButton
        function EpochDataButtonPushed(app, event)
            if strcmp(app.mode, 'resting') % no triggers
                app.data = epoch_data_5(app.data, 'mode', 'resting', 'length', app.epoch_length, 'overlap', app.overlap);
            else % triggers exist
                type_data = app.UITable.Data;
                selected_rows_data = [type_data{:, 1}] == true;
                selected_types = type_data(selected_rows_data, 2);

                value_data = app.UITable_2.Data;
                selected_rows_value = [value_data{:, 1}] == true;
                selected_values = value_data(selected_rows_value, 2);                         

                app.data = epoch_data_5(app.data, 'mode', 'trigger', 'path', app.path, 'event_type', selected_types', 'event_value',  selected_values', 'pre_stim', app.pre_stim, 'post_stim', app.post_stim);
            end
            app.current_step = 6;
            UpdateUI(app);
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            value = app.LengthEditField.Value;
            app.epoch_length = value;
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            value = app.OverlapEditField.Value;
            app.overlap = value;
        end

        % Value changed function: PreStimulusWindowsEditField
        function PreStimulusWindowsEditFieldValueChanged(app, event)
            value = app.PreStimulusWindowsEditField.Value;
            app.pre_stim = value;
        end

        % Value changed function: PostStimulusWindowsEditField
        function PostStimulusWindowsEditFieldValueChanged(app, event)
            value = app.PostStimulusWindowsEditField.Value;
            app.post_stim = value;
        end

        % Button pushed function: RemoveBadEpochsButton
        function RemoveBadEpochsButtonPushed(app, event)
            app.data = remove_bad_epochs_6(app.data);
            app.current_step = 7;
            UpdateUI(app);
        end

        % Button pushed function: RunICAButton
        function RunICAButtonPushed(app, event)
            app.elec = app.ElectrodeDropDown.Value;
            if strcmp(app.elec, 'Not Selected')
                fprintf("Test");
                app.PleaseselectanelectrodeLabel.Visible = "on";           
            else
                app.PleaseselectanelectrodeLabel.Visible = "off";
                app.RunningICApleasewaitLabel.Visible = "on";               
                drawnow;
                app.comp = calculate_and_plot_ica_7(app.elec, app.data);
                if length(app.comp.label) > 20
                    app.comp_num = 20;
                else
                    app.comp_num = length(app.comp.label);
                end
                app.RunningICApleasewaitLabel.Visible = "off";
                app.Panel_3.Visible = "on";
                app.Label.Visible = "on";
                app.RunICAButton.BackgroundColor = [0.85 0.85 0.85];
                app.RunICAButton.FontColor = [0 0 0];
                app.RejectComponentsButton.BackgroundColor = [0 0.5 0];
                app.RejectComponentsButton.FontColor = [1 1 1];
                drawnow;
            end
        end

        % Button pushed function: RejectComponentsButton
        function RejectComponentsButtonPushed(app, event)
            dropped_comp = [];
            for i=1:app.comp_num
                if app.GridLayout.Children(i).Value == true
                    dropped_comp(end+1) = str2double(app.GridLayout.Children(i).Text);
                end
            end
            app.data = reject_components_7(app.comp, dropped_comp, app.data);
            app.current_step = 8;
            UpdateUI(app);
        end

        % Button pushed function: InterpolateBadChannelsButton
        function InterpolateBadChannelsButtonPushed(app, event)
            if isempty(app.dropped_labels)
                app.NobadchannelsdetectedLabel.Visible = 'on';
            else
                app.elec = app.ElectrodeDropDown.Value;                                   
                app.data = interpolate_bad_channels_8(app.elec, app.dropped_labels, app.data);                
            end
            app.current_step = 9;
            UpdateUI(app);
        end

        % Button pushed function: ApplyartifactrejectionButton
        function ApplyartifactrejectionButtonPushed(app, event)
            app.data = apply_artifact_rejection_9(app.data);
            app.current_step = 10; % finished preprocessing
            UpdateUI(app);
        end

        % Button pushed function: ComparefirstandlastdataButton
        function ComparefirstandlastdataButtonPushed(app, event)
            cfg = [];
            cfg.ylim = [-30 30];
            cfg.allowoverlap = 'yes';            
            raw_data = load_data_0(app.path);            
            ft_databrowser(cfg, raw_data);
            ft_databrowser(cfg, app.data);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_2;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_3;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_4;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % select the location
            [file, location] = uiputfile('*.mat', 'Save your EEG data');
            
            % check if the user clicked cancel
            if isequal(file, 0)
                return;
            end
            
            % compose the path and export the data
            save_path = fullfile(location, file);
            preprocessed_data = app.data;
            save(save_path, "preprocessed_data");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.SelectEEGdataLabel = uilabel(app.GridLayout2);
            app.SelectEEGdataLabel.Text = 'Select EEG data';
            app.SelectButton = uibutton(app.GridLayout2, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ResampleButton = uibutton(app.GridLayout2, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.ApplyFiltersButton = uibutton(app.GridLayout2, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.DropBadChannelsButton = uibutton(app.GridLayout2, 'push');
            app.DropBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @DropBadChannelsButtonPushed, true);
            app.DropBadChannelsButton.Text = 'Drop Bad Channels';
            app.RereferenceDataButton = uibutton(app.GridLayout2, 'push');
            app.RereferenceDataButton.ButtonPushedFcn = createCallbackFcn(app, @RereferenceDataButtonPushed, true);
            app.RereferenceDataButton.Text = 'Re-reference Data';
            app.EditFieldLabel = uilabel(app.GridLayout2);
            app.EditFieldLabel.Text = 'Resampling Frequency';
            app.ResamplingFrequencyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ResamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ResamplingFrequencyEditFieldValueChanged, true);
            app.ResamplingFrequencyEditField.Value = 500;
            app.EditFieldLabel_2 = uilabel(app.GridLayout2);
            app.EditFieldLabel_2.Text = 'High-Pass Filter Frequency';
            app.ResamplingFrequencyEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.ResamplingFrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResamplingFrequencyEditField_2ValueChanged, true);
            app.ResamplingFrequencyEditField_2.Value = 0.5;
            app.EditFieldLabel_3 = uilabel(app.GridLayout2);
            app.EditFieldLabel_3.Text = 'Low-Pass Filter Frequency';
            app.ResamplingFrequencyEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.ResamplingFrequencyEditField_3.ValueChangedFcn = createCallbackFcn(app, @ResamplingFrequencyEditField_3ValueChanged, true);
            app.ResamplingFrequencyEditField_3.Value = 70;
            app.EditFieldLabel_4 = uilabel(app.GridLayout2);
            app.EditFieldLabel_4.Text = 'Notch Filter Frequency';
            app.NotchFilterFrequencyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NotchFilterFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @NotchFilterFrequencyEditFieldValueChanged, true);
            app.NotchFilterFrequencyEditField.Value = 50;
            app.Step0LoadDataLabel = uilabel(app.GridLayout2);
            app.Step0LoadDataLabel.Text = 'Step 0: Load Data';
            app.Step1ResampleDataLabel = uilabel(app.GridLayout2);
            app.Step1ResampleDataLabel.Text = 'Step 1: Resample Data';
            app.Step2ApplyFiltersLabel = uilabel(app.GridLayout2);
            app.Step2ApplyFiltersLabel.Text = 'Step 2: Apply Filters';
            app.Step3DropBadChannelsLabel = uilabel(app.GridLayout2);
            app.Step3DropBadChannelsLabel.Text = 'Step 3: Drop Bad Channels';
            app.Step4RereferenceDataLabel = uilabel(app.GridLayout2);
            app.Step4RereferenceDataLabel.Text = 'Step 4: Re-reference Data';
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next    >>>';
            app.Step0Check = uilabel(app.GridLayout2);
            app.Step0Check.Text = '';
            app.Step1Check = uilabel(app.GridLayout2);
            app.Step1Check.Text = '';
            app.Step2Check = uilabel(app.GridLayout2);
            app.Step2Check.Text = '';
            app.Step3Check = uilabel(app.GridLayout2);
            app.Step3Check.Text = '';
            app.Step4Check = uilabel(app.GridLayout2);
            app.Step4Check.Text = '';
            app.EEGPreprocessingToolLabel = uilabel(app.GridLayout2);
            app.EEGPreprocessingToolLabel.Text = 'EEG Preprocessing Tool';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MydataincludetriggersButton = uiradiobutton(app.ButtonGroup);
            app.MydataincludetriggersButton.Text = 'My data include triggers';
            app.NotriggersButton = uiradiobutton(app.ButtonGroup);
            app.NotriggersButton.Text = 'No triggers';
            app.NotriggersButton.Value = true;
            app.EpochDataButton = uibutton(app.GridLayout4, 'push');
            app.EpochDataButton.ButtonPushedFcn = createCallbackFcn(app, @EpochDataButtonPushed, true);
            app.EpochDataButton.Text = 'Epoch Data';
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Enable = 'off';
            app.Panel.Visible = 'off';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Select'; 'Event Type'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Panel);
            app.UITable_2.ColumnName = {'Select'; 'Value'};
            app.UITable_2.RowName = {};
            app.EditFieldLabel_5 = uilabel(app.Panel);
            app.EditFieldLabel_5.Text = 'Post-Stimulus Window (s)';
            app.PostStimulusWindowsEditField = uieditfield(app.Panel, 'numeric');
            app.PostStimulusWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @PostStimulusWindowsEditFieldValueChanged, true);
            app.PostStimulusWindowsEditField.Value = 0.8;
            app.EditFieldLabel_6 = uilabel(app.Panel);
            app.EditFieldLabel_6.Text = 'Pre-Stimulus Window (s)';
            app.PreStimulusWindowsEditField = uieditfield(app.Panel, 'numeric');
            app.PreStimulusWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @PreStimulusWindowsEditFieldValueChanged, true);
            app.PreStimulusWindowsEditField.Value = 0.2;
            app.Panel_2 = uipanel(app.GridLayout4);
            app.LengthEditFieldLabel = uilabel(app.Panel_2);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 1;
            app.OverlapEditFieldLabel = uilabel(app.Panel_2);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.Panel_2, 'numeric');
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 0.5;
            app.RemoveBadEpochsButton = uibutton(app.GridLayout4, 'push');
            app.RemoveBadEpochsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBadEpochsButtonPushed, true);
            app.RemoveBadEpochsButton.Text = 'Remove Bad Epochs';
            app.Step5EpochDataLabel = uilabel(app.GridLayout4);
            app.Step5EpochDataLabel.Text = 'Step 5: Epoch Data';
            app.Step6RemoveBadEpochsLabel = uilabel(app.GridLayout4);
            app.Step6RemoveBadEpochsLabel.Text = 'Step 6: Remove Bad Epochs';
            app.NextButton_2 = uibutton(app.GridLayout4, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next    >>>';
            app.Step5Check = uilabel(app.GridLayout4);
            app.Step5Check.Text = '';
            app.EEGPreprocessingToolLabel_2 = uilabel(app.GridLayout4);
            app.EEGPreprocessingToolLabel_2.Text = 'EEG Preprocessing Tool';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.GridLayout5 = uigridlayout(app.Tab_3);
            app.RunICAButton = uibutton(app.GridLayout5, 'push');
            app.RunICAButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAButtonPushed, true);
            app.RunICAButton.Text = 'Run ICA';
            app.RunningICApleasewaitLabel = uilabel(app.GridLayout5);
            app.RunningICApleasewaitLabel.Visible = 'off';
            app.RunningICApleasewaitLabel.Text = 'Running ICA, please wait.';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.Panel_3.Visible = 'off';
            app.GridLayout = uigridlayout(app.Panel_3);
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.Text = '1';
            app.CheckBox_2 = uicheckbox(app.GridLayout);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.GridLayout);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.GridLayout);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.GridLayout);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.GridLayout);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.GridLayout);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.GridLayout);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.GridLayout);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.GridLayout);
            app.CheckBox_10.Text = '10';
            app.CheckBox_11 = uicheckbox(app.GridLayout);
            app.CheckBox_11.Text = '11';
            app.CheckBox_12 = uicheckbox(app.GridLayout);
            app.CheckBox_12.Text = '12';
            app.CheckBox_13 = uicheckbox(app.GridLayout);
            app.CheckBox_13.Text = '13';
            app.CheckBox_14 = uicheckbox(app.GridLayout);
            app.CheckBox_14.Text = '14';
            app.CheckBox_15 = uicheckbox(app.GridLayout);
            app.CheckBox_15.Text = '15';
            app.CheckBox_16 = uicheckbox(app.GridLayout);
            app.CheckBox_16.Text = '16';
            app.CheckBox_17 = uicheckbox(app.GridLayout);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.GridLayout);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.GridLayout);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.GridLayout);
            app.CheckBox_20.Text = '20';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Visible = 'off';
            app.Label.Text = 'Select the components to remove';
            app.RejectComponentsButton = uibutton(app.GridLayout5, 'push');
            app.RejectComponentsButton.ButtonPushedFcn = createCallbackFcn(app, @RejectComponentsButtonPushed, true);
            app.RejectComponentsButton.Text = 'Reject Components';
            app.ElectrodeDropDownLabel = uilabel(app.GridLayout5);
            app.ElectrodeDropDownLabel.Text = 'Electrode';
            app.ElectrodeDropDown = uidropdown(app.GridLayout5);
            app.ElectrodeDropDown.Items = {'Not Selected', 'Option 2', 'Option 3', 'Option 4'};
            app.ElectrodeDropDown.Value = 'Not Selected';
            app.Step7ApplyICALabel = uilabel(app.GridLayout5);
            app.Step7ApplyICALabel.Text = 'Step 7: Apply ICA';
            app.PleaseselectanelectrodeLabel = uilabel(app.GridLayout5);
            app.PleaseselectanelectrodeLabel.Visible = 'off';
            app.PleaseselectanelectrodeLabel.Text = 'Please select an electrode';
            app.NextButton_3 = uibutton(app.GridLayout5, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next    >>>';
            app.Step7Check = uilabel(app.GridLayout5);
            app.Step7Check.Text = '';
            app.EEGPreprocessingToolLabel_3 = uilabel(app.GridLayout5);
            app.EEGPreprocessingToolLabel_3.Text = 'EEG Preprocessing Tool';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.GridLayout6 = uigridlayout(app.Tab_4);
            app.InterpolateBadChannelsButton = uibutton(app.GridLayout6, 'push');
            app.InterpolateBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateBadChannelsButtonPushed, true);
            app.InterpolateBadChannelsButton.Text = 'Interpolate Bad Channels';
            app.ApplyartifactrejectionButton = uibutton(app.GridLayout6, 'push');
            app.ApplyartifactrejectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyartifactrejectionButtonPushed, true);
            app.ApplyartifactrejectionButton.Text = 'Apply artifact rejection';
            app.ComparefirstandlastdataButton = uibutton(app.GridLayout6, 'push');
            app.ComparefirstandlastdataButton.ButtonPushedFcn = createCallbackFcn(app, @ComparefirstandlastdataButtonPushed, true);
            app.ComparefirstandlastdataButton.Text = 'Compare first and last data';
            app.Step8Interpolatebadchannels = uilabel(app.GridLayout6);
            app.Step8Interpolatebadchannels.Text = 'Step 8: Interpolate Bad Channels';
            app.Step9Applyartifactrejection = uilabel(app.GridLayout6);
            app.Step9Applyartifactrejection.Text = 'Step 9: Apply Artifact Rejection';
            app.Compare = uilabel(app.GridLayout6);
            app.Compare.Text = 'Compare';
            app.Step8Check = uilabel(app.GridLayout6);
            app.Step8Check.Text = '';
            app.Step9Check = uilabel(app.GridLayout6);
            app.Step9Check.Text = '';
            app.EEGPreprocessingToolLabel_4 = uilabel(app.GridLayout6);
            app.EEGPreprocessingToolLabel_4.Text = 'EEG Preprocessing Tool';
            app.ExportData = uilabel(app.GridLayout6);
            app.ExportData.Text = 'Export Data';
            app.ExportButton = uibutton(app.GridLayout6, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.NobadchannelsdetectedLabel = uilabel(app.GridLayout6);
            app.NobadchannelsdetectedLabel.Visible = 'off';
            app.NobadchannelsdetectedLabel.Text = {'No bad channels detected.'; 'Skipping interpolation.'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Tab.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.Tab_2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGPreprocessingTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end